classdef one_point_nuc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BGPathTextArea          matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        ResultNameEditField     matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        SendResultButton        matlab.ui.control.Button
        NUCButton               matlab.ui.control.Button
        ProcessModeButtonGroup  matlab.ui.container.ButtonGroup
        DivideButton            matlab.ui.control.RadioButton
        SubtractButton          matlab.ui.control.RadioButton
        LoadBackgroundButton    matlab.ui.control.Button
        ArrowImage              matlab.ui.control.Image
        AfterUIAxes             matlab.ui.control.UIAxes
        BeforeUIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        MainData
        Index = 1
        BeforeUIAxesPlot
        AfterUIAxesPlot
        BGData
        ProcessedData % NUC後のデータ
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, main_data)
            app.CallingApp = main_app;
            app.MainData = main_data;

            % 画像の表示
            app.BeforeUIAxesPlot = imagesc(app.BeforeUIAxes, app.MainData(:,:,app.Index));
            app.AfterUIAxesPlot = imagesc(app.AfterUIAxes, app.MainData(:,:,app.Index));
            app.BeforeUIAxes.XLim = [1 size(app.MainData,2)];
            app.BeforeUIAxes.YLim = [1 size(app.MainData,1)];
            app.AfterUIAxes.XLim = [1 size(app.MainData,2)];
            app.AfterUIAxes.YLim = [1 size(app.MainData,1)];
        end

        % Button pushed function: LoadBackgroundButton
        function LoadBackgroundButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat;*.tif'});
            if ~file
                return;
            end
            full_file_path = fullfile(path,file);
            [~,~,ext] = fileparts(full_file_path);
            if strcmp(ext, ".tif")
                image_info = imfinfo(full_file_path);
                app.BGData = zeros(image_info(1).Height, image_info(1).Width, size(image_info, 1));
                for i = 1:size(image_info, 1)
                    app.BGData(:,:,i) = imread(full_file_path, i);
                end
            end
            app.BGData = median(app.BGData,3);
            app.BGPathTextArea.Value = full_file_path;
            drawnow
        end

        % Button pushed function: NUCButton
        function NUCButtonPushed(app, event)
            mean_bg = mean2(app.BGData);
            if app.SubtractButton.Value
                % 画像データはuint16なので負の値にならないように10000足して後で引く
                correction_matrix = uint16(10000) + uint16(app.BGData) - uint16(mean_bg);
                app.ProcessedData = uint16(app.MainData) - correction_matrix + uint16(10000);
            end
            app.AfterUIAxesPlot.CData = app.ProcessedData(:,:,app.Index);
            colorbar(app.AfterUIAxes)
            size(app.ProcessedData)
        end

        % Button pushed function: SendResultButton
        function SendResultButtonPushed(app, event)
           add_data(app.CallingApp,double(app.ProcessedData),app.ResultNameEditField.Value) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeforeUIAxes = uiaxes(app.UIFigure);
            title(app.BeforeUIAxes, 'Before')
            xlabel(app.BeforeUIAxes, 'X')
            ylabel(app.BeforeUIAxes, 'Y')
            zlabel(app.BeforeUIAxes, 'Z')
            app.AfterUIAxes = uiaxes(app.UIFigure);
            title(app.AfterUIAxes, 'After')
            xlabel(app.AfterUIAxes, 'X')
            ylabel(app.AfterUIAxes, 'Y')
            zlabel(app.AfterUIAxes, 'Z')
            app.ArrowImage = uiimage(app.UIFigure);
            app.ArrowImage.ImageSource = fullfile(pathToMLAPP, 'images', 'arrow1.png');
            app.LoadBackgroundButton = uibutton(app.UIFigure, 'push');
            app.LoadBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBackgroundButtonPushed, true);
            app.LoadBackgroundButton.Text = 'バックグラウンドを読み込む';
            app.ProcessModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessModeButtonGroup.Title = '処理方法';
            app.SubtractButton = uiradiobutton(app.ProcessModeButtonGroup);
            app.SubtractButton.Text = '減算';
            app.SubtractButton.Value = true;
            app.DivideButton = uiradiobutton(app.ProcessModeButtonGroup);
            app.DivideButton.Text = '除算';
            app.NUCButton = uibutton(app.UIFigure, 'push');
            app.NUCButton.ButtonPushedFcn = createCallbackFcn(app, @NUCButtonPushed, true);
            app.NUCButton.Text = 'NUC実行';
            app.SendResultButton = uibutton(app.UIFigure, 'push');
            app.SendResultButton.ButtonPushedFcn = createCallbackFcn(app, @SendResultButtonPushed, true);
            app.SendResultButton.Text = '結果を転送';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '結果の変数名';
            app.ResultNameEditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'バックグラウンドのパス';
            app.BGPathTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_point_nuc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end